classdef MFAgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MFAUIFigure              matlab.ui.Figure
        LOGDropDownLabel         matlab.ui.control.Label
        LOGDropDown              matlab.ui.control.DropDown
        PlotNewButton            matlab.ui.control.Button
        PlotCurrentButton        matlab.ui.control.Button
        GROUPListBoxLabel        matlab.ui.control.Label
        GROUPListBox             matlab.ui.control.ListBox
        SIGNALListBoxLabel       matlab.ui.control.Label
        SIGNALListBox            matlab.ui.control.ListBox
        ZeroShiftTimeCheckBox    matlab.ui.control.CheckBox
        SweepRangeDropDownLabel  matlab.ui.control.Label
        SweepRangeDropDown       matlab.ui.control.DropDown
        UpdateDatabaseButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mfa MFlightAnalysis; % database
        activeLogIdx = 1;
        sweepTimes;
    end
    
    methods (Access = private)
        
        function updateMFAData(app)
            % log drop down
            app.updateLogList
            app.updateGroupList
            app.updateSignalList
            app.updateSweepTimes
        end
        function updateLogList(app)
            logNum = app.mfa.availableLogs;
            logStr = arrayfun(@(x) num2str(x), logNum,'UniformOutput', false);
            app.LOGDropDown.Items = logStr;
        end
        function updateSweepTimes(app)
            try
                [~, app.sweepTimes] = app.mfa.getSweepIndices('logIndex', app.activeLogIdx);
                swps = arrayfun(@(x) sprintf('%d',x), 1:size(app.sweepTimes, 1), 'UniformOutput', false);
                opt = horzcat({'-'},swps);
            catch
                opt = {'-'};
            end
            app.SweepRangeDropDown.Items = opt;
        end
        function updateGroupList(app)
            grps = sort(app.mfa.logs{app.activeLogIdx}.log.msgsContained);
            % filter out empty groups
            validGroups = cellfun(@(x) ~isempty(app.mfa.logs{app.activeLogIdx}.log.(x).TimeS), grps);
            app.GROUPListBox.Items = grps(validGroups);
        end
        function updateLogIdx(app)
            app.activeLogIdx = app.mfa.getLogIdx(app.LOGDropDown.Value);
        end
        function updateSignalList(app)
            excludeList = {'LineNo', 'TimeS', 'DatenumUTC', 'TimeUS'};    
            group   = app.GROUPListBox.Value;
            grp     = app.mfa.logs{app.activeLogIdx}.log.(group);
            fn      = fieldnames(grp);
            fn(cellfun(@(x) any(strcmp(x, excludeList)), fn)) = [];
            keep    = false(size(fn));
            tLength = length(grp.TimeS);
            for j=1:numel(fn)
                if isnumeric(grp.(fn{j})) && length(grp.(fn{j})) == tLength
                    keep(j) = true;
                end
            end
            app.SIGNALListBox.Items = sort(fn(keep));
        end
        function plotSignal(app, group, signal)
            sr = app.SweepRangeDropDown.Value;
            tmp = app.mfa.getSignal( app.activeLogIdx, group, signal);
            if ~strcmp(sr, '-')
               srn = str2double(sr);
               iStart = find(tmp.time <= app.sweepTimes(srn, 1), 1, 'last');
               iStop  = find(tmp.time >= app.sweepTimes(srn, 2), 1, 'first');
               tmp.time = tmp.time(iStart:iStop);
               tmp.(signal) = tmp.(signal)(iStart:iStop);
            end
            if (app.ZeroShiftTimeCheckBox.Value)
                tmp.time = tmp.time - tmp.time(1);
            end
            plot(tmp.time, tmp.(signal))
            ylabel(tmp.unit)
            xlabel('Time (s)')
            title(signal)
            grid on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            baseVars = evalin('base','whos');
            baseMFA  = baseVars(arrayfun(@(x) strcmp(x.class, 'MFlightAnalysis'), baseVars));
            if isempty(baseMFA)
                error('no base vars of correct type')
            elseif numel(baseMFA) > 1
                error('more than one instance')
            end
            app.mfa = evalin('base',baseMFA.name);
            app.updateMFAData
        end

        % Value changed function: GROUPListBox
        function GROUPListBoxValueChanged(app, event)
            app.updateSignalList
        end

        % Value changed function: LOGDropDown
        function LOGDropDownValueChanged(app, event)
            value = app.LOGDropDown.Value;
            app.activeLogIdx = app.mfa.getLogIdx(str2double(value));
            app.updateSweepTimes
            app.updateGroupList
            app.updateSignalList
        end

        % Button pushed function: PlotNewButton
        function PlotNewButtonPushed(app, event)
            figure
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
        end

        % Button pushed function: PlotCurrentButton
        function PlotCurrentButtonPushed(app, event)
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MFAUIFigure = uifigure('Visible', 'off');
            app.MFAUIFigure.Name = 'MFA';
            app.LOGDropDownLabel = uilabel(app.MFAUIFigure);
            app.LOGDropDownLabel.Text = 'LOG';
            app.LOGDropDown = uidropdown(app.MFAUIFigure);
            app.LOGDropDown.ValueChangedFcn = createCallbackFcn(app, @LOGDropDownValueChanged, true);
            app.PlotNewButton = uibutton(app.MFAUIFigure, 'push');
            app.PlotNewButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNewButtonPushed, true);
            app.PlotNewButton.Text = 'Plot New';
            app.PlotCurrentButton = uibutton(app.MFAUIFigure, 'push');
            app.PlotCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentButtonPushed, true);
            app.PlotCurrentButton.Text = 'Plot Current';
            app.GROUPListBoxLabel = uilabel(app.MFAUIFigure);
            app.GROUPListBoxLabel.Text = 'GROUP';
            app.GROUPListBox = uilistbox(app.MFAUIFigure);
            app.GROUPListBox.ValueChangedFcn = createCallbackFcn(app, @GROUPListBoxValueChanged, true);
            app.SIGNALListBoxLabel = uilabel(app.MFAUIFigure);
            app.SIGNALListBoxLabel.Text = 'SIGNAL';
            app.SIGNALListBox = uilistbox(app.MFAUIFigure);
            app.ZeroShiftTimeCheckBox = uicheckbox(app.MFAUIFigure);
            app.ZeroShiftTimeCheckBox.Text = 'Zero Shift Time';
            app.ZeroShiftTimeCheckBox.Value = true;
            app.SweepRangeDropDownLabel = uilabel(app.MFAUIFigure);
            app.SweepRangeDropDownLabel.Text = 'Sweep Range';
            app.SweepRangeDropDown = uidropdown(app.MFAUIFigure);
            app.UpdateDatabaseButton = uibutton(app.MFAUIFigure, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Text = 'Update Database';
            % Show the figure after all components are created
            app.MFAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFAgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MFAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MFAUIFigure)
        end
    end
end